classdef dsc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        text3              matlab.ui.control.Label
        Start_button       matlab.ui.control.Button
        Cancel_button      matlab.ui.control.Button
        uipanel3           matlab.ui.container.Panel
        rho_input          matlab.ui.control.EditField
        r2_star_input      matlab.ui.control.EditField
        TR_input           matlab.ui.control.EditField
        TE_input           matlab.ui.control.EditField
        Psvd_input         matlab.ui.control.EditField
        text5              matlab.ui.control.Label
        text6              matlab.ui.control.Label
        text7              matlab.ui.control.Label
        text8              matlab.ui.control.Label
        text9              matlab.ui.control.Label
        text14             matlab.ui.control.Label
        text15             matlab.ui.control.Label
        text16             matlab.ui.control.Label
        text17             matlab.ui.control.Label
        species_selection  matlab.ui.container.ButtonGroup
        mouse_button       matlab.ui.control.RadioButton
        human_button       matlab.ui.control.RadioButton
        uipanel8           matlab.ui.container.Panel
        dsc_image_path     matlab.ui.control.EditField
        remoive_images     matlab.ui.control.Button
        add_images         matlab.ui.control.Button
        uipanel12          matlab.ui.container.Panel
        AIF_path           matlab.ui.control.EditField
        add_AIF_roi        matlab.ui.control.Button
        aif_selection      matlab.ui.container.ButtonGroup
        auto_cluster       matlab.ui.control.RadioButton
        user_aif           matlab.ui.control.RadioButton
        import_aif1        matlab.ui.control.RadioButton
        Import_stored      matlab.ui.control.RadioButton
        add_import_aif     matlab.ui.control.Button
        import_path        matlab.ui.control.EditField
        Restart            matlab.ui.control.Button
        text21             matlab.ui.control.Label
        uipanel14          matlab.ui.container.Panel
        noise_selection    matlab.ui.container.ButtonGroup
        auto_Noise         matlab.ui.control.RadioButton
        user_Noise         matlab.ui.control.RadioButton
        add_noise_roi      matlab.ui.control.Button
        noise_file         matlab.ui.control.EditField
        Fitting_function   matlab.ui.container.ButtonGroup
        forcelb            matlab.ui.control.RadioButton
        rawdata            matlab.ui.control.RadioButton
        biexp              matlab.ui.control.RadioButton
        biexl              matlab.ui.control.RadioButton
        Gammav             matlab.ui.control.RadioButton
        upslopebiexp       matlab.ui.control.RadioButton
        upslopebiexpliadj  matlab.ui.control.RadioButton
        forcebiexp         matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DSC_gui_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to DSC_gui (see VARARGIN)
            
            % Choose default command line output for DSC_gui
            handles.output = hObject;
            
            %ADDITIONAL BOXES IN THE OUTPUT STRUCTURE:
            handles.dsc_image = [];
            handles.AIF_roi = [];
            handles.import_aif = [];
            handles.import_store = [];
            handles.noise_roi = [];
            handles.TE = [];
            handles.TR = [];
            handles.rho = [];
            handles.Psvd = [];
            handles.species = 'mouse';
            handles.r2_star = [];
            handles.AIF_type = 0;
            handles.noise_type = 0;
            handles.Fitting_function = 0;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Selection changed function: Fitting_function
        function Fitting_function_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in Fitting_function
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.forcelb,'Value',1);
            set(handles.biexp,'Value',0);
            set(handles.biexl,'Value',0);
            set(handles.Gammav,'Value', 0);
            set(handles.rawdata,'Value', 0);
            set(handles.upslopebiexp,'Value', 0);
            set(handles.upslopebiexpliadj,'Value', 0);
            set(handles.forcebiexp,'Value',0);
            
            switch get(eventdata.NewValue,'Tag') % Get Tag of selected object.
                case 'forcelb'
                    handles.Fitting_function =  0;
                    set(handles.forcelb,'Value',1);
                    set(handles.biexp,'Value',0);
                    set(handles.biexl,'Value',0);
                    set(handles.Gammav,'Value', 0);
                    set(handles.rawdata,'Value', 0);
                    set(handles.upslopebiexp,'Value', 0);
                    set(handles.upslopebiexpliadj,'Value', 0);
                    set(handles.forcebiexp,'Value',0);
                case 'biexp'
                    handles.Fitting_function = 1;
                    set(handles.forcelb,'Value',0);
                    set(handles.biexp,'Value',1);
                    set(handles.biexl,'Value',0);
                    set(handles.Gammav,'Value', 0);
                    set(handles.rawdata,'Value', 0);
                    set(handles.upslopebiexp,'Value', 0);
                    set(handles.upslopebiexpliadj,'Value', 0);
                    set(handles.forcebiexp,'Value',0);
                case 'biexl'
                    handles.Fitting_function = 2;
                    set(handles.forcelb,'Value',0);
                    set(handles.biexp,'Value',0);
                    set(handles.biexl,'Value',1);
                    set(handles.Gammav,'Value', 0);
                    set(handles.rawdata,'Value', 0);
                    set(handles.upslopebiexp,'Value', 0);
                    set(handles.upslopebiexpliadj,'Value', 0);
                    set(handles.forcebiexp,'Value',0);
                case 'Gammav'
                    handles.Fitting_function = 3;
                    set(handles.forcelb,'Value',0);
                    set(handles.biexp,'Value',0);
                    set(handles.biexl,'Value',0);
                    set(handles.Gammav,'Value', 1);
                    set(handles.rawdata,'Value', 0);
                    set(handles.upslopebiexp,'Value', 0);
                    set(handles.upslopebiexpliadj,'Value', 0);
                    set(handles.forcebiexp,'Value',0);
                case 'rawdata'
                    handles.Fitting_function = 4;
                    set(handles.forcelb,'Value',0);
                    set(handles.biexp,'Value',0);
                    set(handles.biexl,'Value',0);
                    set(handles.Gammav,'Value', 0);
                    set(handles.rawdata,'Value', 1);
                    set(handles.upslopebiexp,'Value', 0);
                    set(handles.upslopebiexpliadj,'Value', 0);
                    set(handles.forcebiexp,'Value',0);
                case 'upslopebiexp'
                    handles.Fitting_function = 5;
                    set(handles.forcelb,'Value',0);
                    set(handles.biexp,'Value',0);
                    set(handles.biexl,'Value',0);
                    set(handles.Gammav,'Value', 0);
                    set(handles.rawdata,'Value', 0);
                    set(handles.upslopebiexp,'Value', 1);
                    set(handles.upslopebiexpliadj,'Value', 0);
                    set(handles.forcebiexp,'Value',0);
                case 'upslopebiexpliadj'
                    handles.Fitting_function = 6;
                    set(handles.forcelb,'Value',0);
                    set(handles.biexp,'Value',0);
                    set(handles.biexl,'Value',0);
                    set(handles.Gammav,'Value', 0);
                    set(handles.rawdata,'Value', 0);
                    set(handles.upslopebiexp,'Value', 0);
                    set(handles.upslopebiexpliadj,'Value', 1);
                    set(handles.forcebiexp,'Value',0);
                case 'forcebiexp'
                    handles.Fitting_function = 7;
                    set(handles.forcelb,'Value',0);
                    set(handles.biexp,'Value',0);
                    set(handles.biexl,'Value',0);
                    set(handles.Gammav,'Value', 0);
                    set(handles.rawdata,'Value', 0);
                    set(handles.upslopebiexp,'Value', 0);
                    set(handles.upslopebiexpliadj,'Value', 0);
                    set(handles.forcebiexp,'Value',1);
            end
            guidata(hObject, handles);
        end

        % Value changed function: Psvd_input
        function Psvd_input_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Psvd_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Psvd_input as text
            %        str2double(get(hObject,'String')) returns contents of Psvd_input as a double
            handles.Psvd = str2double(get(hObject,'String'));
            uiremember;
            guidata(hObject, handles);
        end

        % Button pushed function: Start_button
        function Start_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Start_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Unboxing the variables from the handles structure:
            TE = str2double(get(handles.TE_input,'String'))/1000.0; %conversion from miliseconds to seconds 12/15/17
            TR = str2double(get(handles.TR_input,'String'));%/1000.0;
            deltaT = TR / 60;  % converstion to minutes.
            r2_star = str2double(get(handles.r2_star_input,'String'));
            species = handles.species;
            Psvd = str2double(get(handles.Psvd_input,'String'));
            rho =  str2double(get(handles.rho_input,'String'));
            AIF_type = handles.AIF_type;
            Fitting_function = handles.Fitting_function;
            noise_type = handles.noise_type;
            details = whos('r2_star');
            
            %Next WE ARE GOING TO LOAD THE IMAGE FILE:
            disp(handles.dsc_image);
            disp(handles.species);
            disp(handles.r2_star);
            image_array = load_nii(handles.dsc_image);
            image_array = image_array.img;
            %Next we load the nosie roi array:
            if noise_type == 0
                roi_array = [];
            elseif noise_type == 1
                roi_array = load_nii(handles.noise_roi);
                roi_array = roi_array.img;
            end
            
            
            %getting the path from the image file:
            [ ~ , image_path] = fileparts(handles.dsc_image);
            
            [concentration_array, base_concentration_array, time_vect, base_time_vect,whole_time_vect, bolus_time] = DSC_signal2concentration(image_array,TE,TR,r2_star,species,image_path,noise_type, ...
               roi_array );
            if AIF_type == 0 %AIF Auto
                [meanAIF, meanSignal] = AIF_auto_cluster(concentration_array, image_array, time_vect, TR,species);
                baseline = 0; %develop a way to calculate the baseline from the auto clustered AIF
                baseline_array = zeros(numel(base_time_vect));
            elseif AIF_type ==1 %AIF User Selected
                AIF_mask = load_nii(handles.AIF_roi);
                AIF_mask = AIF_mask.img;
                [meanAIF, meanSignal, baseline,baseline_array] = AIF_manual_noreshape(image_array,concentration_array,AIF_mask, base_time_vect,base_concentration_array);
            
            elseif AIF_type==2 %AIF Import
                load(handles.import_AIF)
                [meanAIF_adjusted, time_vect, concentration_array] = import_AIF(meanAIF, bolus_time, time_vect, concentration_array, r2_star, TE);
                meanAIF = meanAIF_adjusted;
            
                display('time_vect_num')
                numel(time_vect)
                display('meanAIF_num')
                numel(meanAIF)
            
            elseif AIF_type==3 %AIF Use Previous
                load('previous_data.mat');
                [meanAIF_adjusted, time_vect, concentration_array] = previous_AIF(meanAIF,meanSignal,bolus_time, time_vect,concentration_array);
                meanAIF = meanAIF_adjusted;
            
                display('time_vect_num')
                numel(time_vect)
                display('meanAIF_num')
                numel(meanAIF)
            end
            
            %create a mean AIF spaning the whole scan time
            AIF_whole = cat(1,baseline_array, meanAIF);
            
            %now run the selected fitting function
            if Fitting_function == 0 %forced linear biexponential (uses local max) %the upslope is fitted to
            
                Cp = cat(1,baseline_array,meanAIF);
                step = [(bolus_time) (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = Single_Forced_linear_AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                Ct = Ct';
            
            elseif Fitting_function == 1 %biexponential (uses absolute max)
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = AIFbiexpfithelp(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                Ct = Ct';
            
            elseif Fitting_function == 2 %biexponential (uses local max)
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                Ct = Ct';
            
            elseif Fitting_function == 3 %gamma-variant
                % Now we fit the AIF with a SCR model:
            
                %assigning the gamma variate function, gfun, to be our desired fitting
                %function:
                Ct = fitting_gamma_variant(meanAIF,species, time_vect);
                Cp = cat(1,baseline_array, Ct); %Cp is created for plotting purposes only. Ct is analyzed for CBF, CBV...
            
            elseif Fitting_function == 4 %raw data
                Ct = meanAIF;
                Cp = cat(1,baseline_array, Ct);
            
            %{
            elseif Fitting_function == 42 %copy_of_upslopewith peak based decision making (not currently an option)
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = Forced_linear_AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                [~,max_indexCt] = max(Ct);
            
                if numel(findpeaks(AIF_whole(bolus_time:((bolus_time + 1) + max_indexCt)))) == 1 %check for additional local maxima in upslope, if none
                    %are present use the exact upslope vales for the fitted funciton
                    Ct(1:max_indexCt) = meanAIF(1: max_indexCt);
                    Ct = Ct';
                    base = baseline * ones(numel(baseline_array),1);
                    Cp = [base; Ct];
                else %if additional local maxima or 'bumps' in the upslope are present, use the 2 point forced linear fit
                    Ct = Ct';
                end
            %}
            elseif Fitting_function == 5 %copy_of_upslope with peak based decision making
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time -1) = [];
            
               %calculate the most likely first peak by finding the first local maxima
                %as was done in the local fitting help function
                %peak is the first within 3% of the max value of the whole data set
                [local_maxima, maxima_indexes] = findpeaks(Ct);
                maxima_iterator = 1;
                while(local_maxima(maxima_iterator) < (0.97 * max(local_maxima)))
                    maxima_iterator = maxima_iterator + 1;
                end
            
                max_indexCt = maxima_indexes(maxima_iterator);
            
                if numel(findpeaks(AIF_whole(bolus_time:(bolus_time + max_indexCt)))) == 1 %check for additional local maxima in upslope, if none
                    %are present use the exact upslope vales for the fitted funciton
                    %[bolus time, max_index + 1] is the range that is examined for the
                    %additional maxima
                    Ct(1:max_indexCt) = meanAIF(1:max_indexCt);
                    Ct = Ct';
                    base = baseline * ones(numel(baseline_array),1);
                    Cp = [base; Ct];
                else %if additional local maxima or 'bumps' in the upslope are present, use a smooth fit
                    Ct = Ct';
                end
            
            elseif Fitting_function == 6 %upslope copy biexponetial
            
                Cp = cat(1,baseline_array,meanAIF);
                step = [bolus_time (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
            
                %calculate the most likely first peak by finding the first local maxima
                %as was done in the local fitting help function
                %peak is the first within 3% of the max value of the whole data set
                [local_maxima, maxima_indexes] = findpeaks(Ct);
                maxima_iterator = 1;
                while(local_maxima(maxima_iterator) < (0.97 * max(local_maxima)))
                    maxima_iterator = maxima_iterator + 1;
                end
            
                max_indexCt = maxima_indexes(maxima_iterator);
            
                max_indexCt = maxima_indexes(maxima_iterator);
            
                %exact upslope vales for the fitted funciton
                Ct(1:max_indexCt) = meanAIF(1:max_indexCt);
                Ct = Ct';
                base = baseline * ones(numel(baseline_array),1);
                Cp = [base; Ct];
            
            elseif Fitting_function == 7 %forced bilinear biexponential decay
            
                %two linear function
                Cp = cat(1,baseline_array,meanAIF);
                step = [(bolus_time) (bolus_time + numel(time_vect))];
                T1 = whole_time_vect;
                xdata = struct('Cp',Cp,'baseline', baseline, 'timer', T1, 'step', step, 'bolus_time', bolus_time);
                verbose = -1; %this prevents any internal verbose function from running change to 1 to run verbose
            
                [Cp, x, xdata, rsqurare] = Forced_linear_AIFbiexpfithelplocal(xdata,verbose);
                Ct = Cp;
                Ct(1:bolus_time - 1) = [];
                Ct = Ct';
            
            end
            
            %save this runs meanAIF, bolus time, and importedAIF
            save('previous_data.mat', 'meanAIF','meanSignal','bolus_time','baseline');
            
            %plot the meanAIF and fitted function over the entire scan
            figure;
            plot(whole_time_vect,Cp,'-o', whole_time_vect, AIF_whole);
            title('AIF and Fitted AIF Over time');
            xlabel('Time (min)');
            ylabel('Concentration (mM)');
            legend('Fitted AIF', 'Raw AIF');
            
            %plot the mean AIF and fitted function over the time period to be analyzed
            figure;
            time_vect_sec = time_vect * 60; %get time in seconds to plot
            time_vect_sec = time_vect_sec + (bolus_time);
            %base_time_vect_sec = base_time_vect * 60;
            plot(time_vect_sec,Ct,'-o',time_vect_sec,meanAIF);
            title('AIF and Fitted AIF Over time');
            xlabel('Time (s)');
            ylabel('Concentration (mM)');
            legend('Fitted AIF', 'Raw AIF');
            
            %plot the mean AIF signal
            figure;
            time_vect_sec2 = 0 : length(meanSignal) -1;
            time_vect_sec2 = time_vect_sec2 * TR;
            plot(time_vect_sec2, meanSignal, 'b');
            title('AIF Mean Signal from Start to End of Scan')
            xlabel('Time (s)')
            ylabel('Signal Intensity (au)')
            
            Kh = 0.71;
            % method = 1;
            [CBF, CBV, MTT] = DSC_convolution_sSVD(concentration_array,Ct,deltaT,Kh,rho,Psvd,1,image_path);
            disp('new')
        end

        % Value changed function: TE_input
        function TE_input_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TE_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TE_input as text
            %        str2double(get(hObject,'String')) returns contents of TE_input as a double
            handles.TE = str2double(get(hObject,'String'));
            uiremember;
            guidata(hObject, handles);
        end

        % Value changed function: TR_input
        function TR_input_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TR_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TR_input as text
            %        str2double(get(hObject,'String')) returns contents of TR_input as a double
            handles.TR = str2double(get(hObject,'String'));
            uiremember;
            guidata(hObject, handles);
        end

        % Button pushed function: add_AIF_roi
        function add_AIF_roi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to add_AIF_roi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % When this button gets pressed, open a dialog window to select a file:
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                set(handles.AIF_path,'String',visualpath);
                handles.AIF_roi = path_out;
            
            end
            guidata(hObject, handles);
        end

        % Button pushed function: add_images
        function add_images_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to add_images (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % When this button gets pressed, open a dialog window to select a file:
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                set(handles.dsc_image_path,'String',visualpath);
                handles.dsc_image = path_out;
            
            end
            
            guidata(hObject, handles);
        end

        % Button pushed function: add_import_aif
        function add_import_aif_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to add_import_aif (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                set(handles.import_path,'String',visualpath);
                handles.AIF_roi = path_out;
            
            end
            guidata(hObject, handles);
        end

        % Button pushed function: add_noise_roi
        function add_noise_roi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to add_noise_roi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname, filterspec] = uigetfile('*.nii','Nifti Files (*.nii)');
            
            if isequal(filename,0)
                %disp('User selected Cancel')
                fullpath = '';
            else
                %disp(['User selected ', fullfile(pathname, filename)])
            
                % Combine path and filename together
                fullpath = strcat(pathname,filename);
                path_out = fullpath;
            
                if ischar(fullpath)
                    fullpath = {fullpath};
                end
                % if isempty(list)
                %     list = {''};
                % end
            
                %filename = filename';
                fullpath = fullpath';
            
                if numel(fullpath) > 1
                    visualpath = [fullpath{1} ' -> ' num2str(numel(fullpath)) ' files'];
                else
                    visualpath = fullpath{1};
                end
            
                set(handles.noise_file,'String',visualpath);
                handles.noise_roi = path_out;
            
            end
            guidata(hObject, handles);
        end

        % Selection changed function: aif_selection
        function aif_selection_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in aif_selection
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.auto_cluster,'Value',1);
            set(handles.user_aif,'Value',0);
            set(handles.import_aif1,'Value',0);
            set(handles.add_AIF_roi,'enable', 'off');
            set(handles.add_import_aif,'enable', 'off');
            set(handles.AIF_path,'enable', 'off');
            set(handles.import_path,'enable', 'off');
            set(handles.import_store,'Value',0);
            
            switch get(eventdata.NewValue,'Tag') % Get Tag of selected object.
                case 'auto_cluster'
                    handles.AIF_type =  0;
                    set(handles.add_AIF_roi,'enable', 'off');
                    set(handles.AIF_path,'enable', 'off');
                    set(handles.import_store,'enable', 'off');
                    set(handles.auto_cluster,'Value',1);
                    set(handles.user_aif,'Value',0);
                    set(handles.import_aif1,'Value',0);
                    set(handles.add_import_aif,'enable', 'off');
                    set(handles.import_path,'enable', 'off');
                    set(handles.import_store,'Value',0);
                case 'user_aif'
                   handles.AIF_type = 1;
                   set(handles.add_AIF_roi,'enable', 'on');
                   set(handles.AIF_path,'enable', 'on');
                   set(handles.auto_cluster,'Value',0);
                   set(handles.user_aif,'Value',1);
                   set(handles.import_aif1,'Value',0);
                   set(handles.add_import_aif,'enable', 'off');
                   set(handles.import_path,'enable', 'off');
                   set(handles.import_store,'Value',0);
                   set(handles.import_store,'enable', 'off');
                case 'import_aif1'
                   handles.AIF_type = 2;
                   set(handles.add_AIF_roi,'enable', 'off');
                   set(handles.AIF_path,'enable', 'off');
                   set(handles.import_store,'enable', 'off');
                   set(handles.import_aif1,'Value',1);
                   set(handles.import_path,'enable', 'on');
                   set(handles.add_import_aif,'enable', 'on');
                   set(handles.auto_cluster,'Value',0);
                   set(handles.user_aif,'Value',0);
                    set(handles.import_store,'Value',0);
                case 'import_stored'
                   handles.AIF_type = 3;
                   set(handles.add_AIF_roi,'enable', 'off');
                   set(handles.AIF_path,'enable', 'off');
                   set(handles.import_store,'enable', 'on');
                   set(handles.import_aif1,'Value',0);
                   set(handles.import_store,'Value',1);
                   set(handles.import_path,'enable', 'off');
                   set(handles.add_import_aif,'enable', 'off');
                   set(handles.auto_cluster,'Value',0);
                   set(handles.user_aif,'Value',0);
            end
            guidata(hObject, handles);
        end

        % Value changed function: noise_file
        function noise_file_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to noise_file (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of noise_file as text
            %        str2double(get(hObject,'String')) returns contents of noise_file as a double
            % Do not enable unless button press...
            set(handles.noise_file,'enable', 'off');
            guidata(hObject, handles);
        end

        % Selection changed function: noise_selection
        function noise_selection_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in noise_selection
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.user_Noise,'Value',0);
            set(handles.auto_Noise,'Value',1);
            switch get(eventdata.NewValue,'Tag') % Get Tag of selected object.
                case 'auto_Noise'
                    handles.noise_type =  0;
                    set(handles.user_Noise,'Value',0);
                    set(handles.auto_Noise,'Value',1);
                    set(handles.add_noise_roi,'enable', 'off');
                    set(handles.noise_file,'enable', 'off');
                case 'user_Noise'
                    handles.noise_type = 1;
                    set(handles.user_Noise,'Value',1);
                    set(handles.auto_Noise,'Value',0);
                    set(handles.add_noise_roi,'enable', 'on');
                    set(handles.noise_file,'enable', 'on');
            end
            guidata(hObject, handles);
        end

        % Value changed function: r2_star_input
        function r2_star_input_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to r2_star_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of r2_star_input as text
            %        str2double(get(hObject,'String')) returns contents of r2_star_input as a double
            handles.r2_star = str2double(get(hObject,'String'));
            uiremember;
            guidata(hObject, handles);
        end

        % Button pushed function: remoive_images
        function remoive_images_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to remoive_images (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.dsc_image_path = [];
            handles.dsc_image = [];
            guidata(hObject, handles);
        end

        % Value changed function: rho_input
        function rho_input_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rho_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of rho_input as text
            %        str2double(get(hObject,'String')) returns contents of rho_input as a double
            handles.rho = str2double(get(hObject,'String'));
            uiremember;
            guidata(hObject, handles);
        end

        % Selection changed function: species_selection
        function species_selection_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in species_selection
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            % Settingn the initial case:
            set(handles.human_button,'Value', 0);
            set(handles.mouse_button,'Value',1);
            switch get(eventdata.NewValue, 'Tag')
                case 'human_button'
                    handles.species = 'human';
                    set(handles.human_button,'Value', 1);
                    set(handles.mouse_button,'Value',0);
                case 'mouse_button'
                    handles.species = 'mouse';
                    set(handles.human_button,'Value', 0);
                    set(handles.mouse_button,'Value',1);
            end
            
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DSC_gui';
            app.figure1.Tag = 'figure1';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'DSC Analysis GUI';
            app.Start_button = uibutton(app.figure1, 'push');
            app.Start_button.ButtonPushedFcn = createCallbackFcn(app, @Start_button_Callback, true);
            app.Start_button.Tag = 'Start_button';
            app.Start_button.Text = 'Start Analysis';
            app.Cancel_button = uibutton(app.figure1, 'push');
            app.Cancel_button.Tag = 'Cancel_button';
            app.Cancel_button.Text = 'Cancel';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Input Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.rho_input = uieditfield(app.uipanel3, 'text');
            app.rho_input.ValueChangedFcn = createCallbackFcn(app, @rho_input_Callback, true);
            app.rho_input.Tag = 'rho_input';
            app.rho_input.Value = '1';
            app.r2_star_input = uieditfield(app.uipanel3, 'text');
            app.r2_star_input.ValueChangedFcn = createCallbackFcn(app, @r2_star_input_Callback, true);
            app.r2_star_input.Tag = 'r2_star_input';
            app.r2_star_input.Value = '1.05';
            app.TR_input = uieditfield(app.uipanel3, 'text');
            app.TR_input.ValueChangedFcn = createCallbackFcn(app, @TR_input_Callback, true);
            app.TR_input.Tag = 'TR_input';
            app.TR_input.Value = '1';
            app.TE_input = uieditfield(app.uipanel3, 'text');
            app.TE_input.ValueChangedFcn = createCallbackFcn(app, @TE_input_Callback, true);
            app.TE_input.Tag = 'TE_input';
            app.TE_input.Value = '19';
            app.Psvd_input = uieditfield(app.uipanel3, 'text');
            app.Psvd_input.ValueChangedFcn = createCallbackFcn(app, @Psvd_input_Callback, true);
            app.Psvd_input.Tag = 'Psvd_input';
            app.Psvd_input.Value = '0.005';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'TE';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Tag = 'text6';
            app.text6.Text = 'TR';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'R2*';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Rho';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Psvd';
            app.text14 = uilabel(app.uipanel3);
            app.text14.Tag = 'text14';
            app.text14.Text = 'ms';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Tag = 'text15';
            app.text15.Text = 'ms';
            app.text16 = uilabel(app.uipanel3);
            app.text16.Tag = 'text16';
            app.text16.Text = 'L/(mmol·s)';
            app.text17 = uilabel(app.uipanel3);
            app.text17.Tag = 'text17';
            app.text17.Text = 'g/ml';
            app.species_selection = uibuttongroup(app.uipanel3);
            app.species_selection.SelectionChangedFcn = createCallbackFcn(app, @species_selection_SelectionChangeFcn, true);
            app.species_selection.Title = 'Species';
            app.species_selection.Tag = 'species_selection';
            app.mouse_button = uiradiobutton(app.species_selection);
            app.mouse_button.Tag = 'mouse_button';
            app.mouse_button.Text = 'Mouse';
            app.mouse_button.Value = true;
            app.human_button = uiradiobutton(app.species_selection);
            app.human_button.Tag = 'human_button';
            app.human_button.Text = 'Human';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = 'Image Selection';
            app.uipanel8.Tag = 'uipanel8';
            app.dsc_image_path = uieditfield(app.uipanel8, 'text');
            app.dsc_image_path.Tag = 'dsc_image_path';
            app.dsc_image_path.Value = 'DSC Image Path (.nii files only)';
            app.remoive_images = uibutton(app.uipanel8, 'push');
            app.remoive_images.ButtonPushedFcn = createCallbackFcn(app, @remoive_images_Callback, true);
            app.remoive_images.Tag = 'remoive_images';
            app.remoive_images.Text = 'Remove Image';
            app.add_images = uibutton(app.uipanel8, 'push');
            app.add_images.ButtonPushedFcn = createCallbackFcn(app, @add_images_Callback, true);
            app.add_images.Tag = 'add_images';
            app.add_images.Text = 'Add Image';
            app.uipanel12 = uipanel(app.figure1);
            app.uipanel12.Title = 'Define AIF';
            app.uipanel12.Tag = 'uipanel12';
            app.AIF_path = uieditfield(app.uipanel12, 'text');
            app.AIF_path.Tag = 'AIF_path';
            app.AIF_path.Enable = 'off';
            app.AIF_path.Value = 'AIF Filepath (.nii files only; same size as Image)';
            app.add_AIF_roi = uibutton(app.uipanel12, 'push');
            app.add_AIF_roi.ButtonPushedFcn = createCallbackFcn(app, @add_AIF_roi_Callback, true);
            app.add_AIF_roi.Tag = 'add_AIF_roi';
            app.add_AIF_roi.Enable = 'off';
            app.add_AIF_roi.Text = 'Click To Add AIF ROI (or enter below)';
            app.aif_selection = uibuttongroup(app.uipanel12);
            app.aif_selection.SelectionChangedFcn = createCallbackFcn(app, @aif_selection_SelectionChangeFcn, true);
            app.aif_selection.Title = 'AIF Selection Type';
            app.aif_selection.Tag = 'aif_selection';
            app.auto_cluster = uiradiobutton(app.aif_selection);
            app.auto_cluster.Tag = 'auto_cluster';
            app.auto_cluster.Text = 'Auto Cluster AIF';
            app.auto_cluster.Value = true;
            app.user_aif = uiradiobutton(app.aif_selection);
            app.user_aif.Tag = 'user_aif';
            app.user_aif.Text = 'User Selected AIF';
            app.import_aif1 = uiradiobutton(app.aif_selection);
            app.import_aif1.Tag = 'import_aif1';
            app.import_aif1.Text = 'Import AIF';
            app.Import_stored = uiradiobutton(app.aif_selection);
            app.Import_stored.Tag = 'Import_stored';
            app.Import_stored.Text = 'Import Previously Stored AIF';
            app.add_import_aif = uibutton(app.uipanel12, 'push');
            app.add_import_aif.ButtonPushedFcn = createCallbackFcn(app, @add_import_aif_Callback, true);
            app.add_import_aif.Tag = 'add_import_aif';
            app.add_import_aif.Enable = 'off';
            app.add_import_aif.Text = 'Click To Import AIF ROI';
            app.import_path = uieditfield(app.uipanel12, 'text');
            app.import_path.Tag = 'import_path';
            app.import_path.Enable = 'off';
            app.import_path.Value = 'Import Filepath (.nii files only; same size as Image)';
            app.Restart = uibutton(app.figure1, 'push');
            app.Restart.Tag = 'Restart';
            app.Restart.Text = 'Restart';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Polymerizing Soon...';
            app.uipanel14 = uipanel(app.figure1);
            app.uipanel14.Title = 'Noise Handling';
            app.uipanel14.Tag = 'uipanel14';
            app.noise_selection = uibuttongroup(app.uipanel14);
            app.noise_selection.SelectionChangedFcn = createCallbackFcn(app, @noise_selection_SelectionChangeFcn, true);
            app.noise_selection.Title = 'Noise Region Selection';
            app.noise_selection.Tag = 'noise_selection';
            app.auto_Noise = uiradiobutton(app.noise_selection);
            app.auto_Noise.Tag = 'auto_Noise';
            app.auto_Noise.Text = 'Automatic';
            app.auto_Noise.Value = true;
            app.user_Noise = uiradiobutton(app.noise_selection);
            app.user_Noise.Tag = 'user_Noise';
            app.user_Noise.Text = 'User Defined';
            app.add_noise_roi = uibutton(app.uipanel14, 'push');
            app.add_noise_roi.ButtonPushedFcn = createCallbackFcn(app, @add_noise_roi_Callback, true);
            app.add_noise_roi.Tag = 'add_noise_roi';
            app.add_noise_roi.Enable = 'off';
            app.add_noise_roi.Text = 'Click to Add Noise ROI';
            app.noise_file = uieditfield(app.uipanel14, 'text');
            app.noise_file.ValueChangedFcn = createCallbackFcn(app, @noise_file_Callback, true);
            app.noise_file.Tag = 'noise_file';
            app.noise_file.Enable = 'off';
            app.noise_file.Value = 'Noise File (.nii; same size as image)';
            app.Fitting_function = uibuttongroup(app.figure1);
            app.Fitting_function.SelectionChangedFcn = createCallbackFcn(app, @Fitting_function_SelectionChangedFcn, true);
            app.Fitting_function.Title = 'Fitting_Function';
            app.Fitting_function.Tag = 'Fitting_function';
            app.forcelb = uiradiobutton(app.Fitting_function);
            app.forcelb.Tag = 'forcelb';
            app.forcelb.Text = 'Forced linear biexponential';
            app.rawdata = uiradiobutton(app.Fitting_function);
            app.rawdata.Tag = 'rawdata';
            app.rawdata.Text = 'Raw Data';
            app.biexp = uiradiobutton(app.Fitting_function);
            app.biexp.Tag = 'biexp';
            app.biexp.Text = 'Biexponential';
            app.biexl = uiradiobutton(app.Fitting_function);
            app.biexl.Tag = 'biexl';
            app.biexl.Text = 'Biexponential local';
            app.Gammav = uiradiobutton(app.Fitting_function);
            app.Gammav.Tag = 'Gammav';
            app.Gammav.Text = 'Gamma-Variant';
            app.Gammav.Value = true;
            app.upslopebiexp = uiradiobutton(app.Fitting_function);
            app.upslopebiexp.Tag = 'upslopebiexp';
            app.upslopebiexp.Text = 'Upslope copy biexponential';
            app.upslopebiexpliadj = uiradiobutton(app.Fitting_function);
            app.upslopebiexpliadj.Tag = 'upslopebiexpliadj';
            app.upslopebiexpliadj.Text = 'Upslope copy biexponential linear adjustment';
            app.forcebiexp = uiradiobutton(app.Fitting_function);
            app.forcebiexp.Tag = 'forcebiexp';
            app.forcebiexp.Text = 'Forced bilinear exponential';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)DSC_gui_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end